classdef UserBWTAuxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_UserBWTAuxApp          matlab.ui.Figure
        panel_ChooseBWT               matlab.ui.container.Panel
        edit_lowesttemp               matlab.ui.control.NumericEditField
        LowesttemperaturerecordedaftersensorcalibrationCLabel  matlab.ui.control.Label
        line                          matlab.ui.control.Label
        label_into                    matlab.ui.control.Label
        label_info1                   matlab.ui.control.Label
        dropdown_sensors              matlab.ui.control.DropDown
        SensorDropDownLabel           matlab.ui.control.Label
        button_continue               matlab.ui.control.Button
        BottomwatertemperatureCLabel  matlab.ui.control.Label
        edit_BWtemp                   matlab.ui.control.NumericEditField
        edit_recordnumber             matlab.ui.control.NumericEditField
        RecordNumberEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        NumberOfSensors
        SensorsToUse
        AllRecords
        PenetrationRecord
        AllSensorsCalibratedTemp

        CurrentSensor 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AllSensorsCalibratedTemp, NumberOfSensors, SensorsToUse, AllRecords, PenetrationRecord)
    
            app.figure_UserBWTAuxApp.WindowStyle = 'modal';
            movegui(app.figure_UserBWTAuxApp, "center");


            app.NumberOfSensors = NumberOfSensors;
            app.SensorsToUse = SensorsToUse;
            app.AllRecords = AllRecords;
            app.PenetrationRecord = PenetrationRecord;
            app.AllSensorsCalibratedTemp = AllSensorsCalibratedTemp;

            % Initiate sensor dropdwn
            app.dropdown_sensors.Items = [string(app.SensorsToUse), 'Top', 'NaN'];
            app.dropdown_sensors.Value = app.dropdown_sensors.Items(end-2);
            app.CurrentSensor = str2double(app.dropdown_sensors.Value);

            % Intitiate edit fields
            app.edit_recordnumber.Value = app.PenetrationRecord-1;
            app.edit_lowesttemp.Value = min(min(app.AllSensorsCalibratedTemp, [],'omitnan'));

            if contains(app.dropdown_sensors.Value, 'Top')
                 app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            else
                app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            end

            
        end

        % Callback function: not associated with a component
        function button_updatePushed(app, event)
            app.UserBWT = app.edit_BWT.Value;
            delete(app)
        end

        % Callback function: not associated with a component
        function button_cancelPushed(app, event)
            delete(app)
            return
            
        end

        % Button pushed function: button_continue
        function button_continueButtonPushed(app, event)
            assignin('base','BottomWaterValue', app.edit_BWtemp.Value)

            delete(app)
        end

        % Value changed function: dropdown_sensors
        function dropdown_sensorsValueChanged(app, event)

            app.CurrentSensor = str2double(app.dropdown_sensors.Value);

            if ~isnan(app.CurrentSensor)
                app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top')
                 app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            end
        end

        % Value changed function: edit_recordnumber
        function edit_recordnumberValueChanged(app, event)

            RecordNumber = app.edit_recordnumber.Value;

            if ~isnan(RecordNumber)
                app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, str2double(app.dropdown_sensors.Value));
                app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            elseif contains(app.dropdown_sensors.Value, 'Top')
                 app.edit_BWtemp.Value = app.AllSensorsCalibratedTemp(app.edit_recordnumber.Value, end);
                 app.edit_BWtemp.Value = round(app.edit_BWtemp.Value, 3);
            end
        end

        % Value changed function: edit_BWtemp
        function edit_BWtempValueChanged(app, event)
            
            app.edit_recordnumber.Value = -999;
            app.dropdown_sensors.Value = app.dropdown_sensors.Items(end);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_UserBWTAuxApp = uifigure('Visible', 'off');
            app.figure_UserBWTAuxApp.Name = 'MATLAB App';
            app.panel_ChooseBWT = uipanel(app.figure_UserBWTAuxApp);
            app.panel_ChooseBWT.Title = 'Choose Bottom Water';
            app.RecordNumberEditField_2Label = uilabel(app.panel_ChooseBWT);
            app.RecordNumberEditField_2Label.Text = 'Record Number:';
            app.edit_recordnumber = uieditfield(app.panel_ChooseBWT, 'numeric');
            app.edit_recordnumber.ValueChangedFcn = createCallbackFcn(app, @edit_recordnumberValueChanged, true);
            app.edit_BWtemp = uieditfield(app.panel_ChooseBWT, 'numeric');
            app.edit_BWtemp.ValueChangedFcn = createCallbackFcn(app, @edit_BWtempValueChanged, true);
            app.BottomwatertemperatureCLabel = uilabel(app.panel_ChooseBWT);
            app.BottomwatertemperatureCLabel.Text = 'Bottom water temperature (°C):';
            app.button_continue = uibutton(app.panel_ChooseBWT, 'push');
            app.button_continue.ButtonPushedFcn = createCallbackFcn(app, @button_continueButtonPushed, true);
            app.button_continue.Text = 'Continue';
            app.SensorDropDownLabel = uilabel(app.panel_ChooseBWT);
            app.SensorDropDownLabel.Text = 'Sensor:';
            app.dropdown_sensors = uidropdown(app.panel_ChooseBWT);
            app.dropdown_sensors.Items = {};
            app.dropdown_sensors.ValueChangedFcn = createCallbackFcn(app, @dropdown_sensorsValueChanged, true);
            app.dropdown_sensors.Value = {};
            app.label_info1 = uilabel(app.panel_ChooseBWT);
            app.label_info1.Text = 'Choose new bottom water temperature:';
            app.label_into = uilabel(app.panel_ChooseBWT);
            app.label_into.Text = 'Information from penetration:';
            app.line = uilabel(app.panel_ChooseBWT);
            app.line.Text = '---------------------------------------------------------------------';
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel = uilabel(app.panel_ChooseBWT);
            app.LowesttemperaturerecordedaftersensorcalibrationCLabel.Text = 'Lowest temperature recorded after sensor calibration (°C):';
            app.edit_lowesttemp = uieditfield(app.panel_ChooseBWT, 'numeric');
            app.edit_lowesttemp.Editable = 'off';
            % Show the figure after all components are created
            app.figure_UserBWTAuxApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserBWTAuxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_UserBWTAuxApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_UserBWTAuxApp)
        end
    end
end